classdef robot_marge < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        CreateRobotTab                matlab.ui.container.Tab
        Joint3Slider_2Label           matlab.ui.control.Label
        CreatetherobotandmoveitusingtheslidersLabel  matlab.ui.control.Label
        Joint3Slider                  matlab.ui.control.Slider
        Joint2Slider                  matlab.ui.control.Slider
        Joint2SliderLabel             matlab.ui.control.Label
        Joint1Slider                  matlab.ui.control.Slider
        Joint1SliderLabel             matlab.ui.control.Label
        CreateRobotButton             matlab.ui.control.Button
        KinematicsTab                 matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        ForwardKinematicsTab          matlab.ui.container.Tab
        Joint3DistanceSpinner         matlab.ui.control.Spinner
        Joint3DistanceSpinnerLabel    matlab.ui.control.Label
        Joint2AngleSpinner            matlab.ui.control.Spinner
        Joint2AngleSpinnerLabel       matlab.ui.control.Label
        Joint1AngleSpinner            matlab.ui.control.Spinner
        Joint1AngleSpinnerLabel       matlab.ui.control.Label
        zEditField                    matlab.ui.control.NumericEditField
        zEditFieldLabel               matlab.ui.control.Label
        yEditField                    matlab.ui.control.NumericEditField
        yEditFieldLabel               matlab.ui.control.Label
        xEditField                    matlab.ui.control.NumericEditField
        xEditFieldLabel               matlab.ui.control.Label
        EnterthejointparametersLabel  matlab.ui.control.Label
        ForwardKinematicsButton       matlab.ui.control.Button
        InverseKinematicsTab          matlab.ui.container.Tab
        ClosedFormIKButton            matlab.ui.control.Button
        NumericalIKButton             matlab.ui.control.Button
        YEditField                    matlab.ui.control.NumericEditField
        YEditFieldLabel               matlab.ui.control.Label
        PEditField                    matlab.ui.control.NumericEditField
        PEditFieldLabel               matlab.ui.control.Label
        REditField                    matlab.ui.control.NumericEditField
        REditField_2Label             matlab.ui.control.Label
        zEditField_2                  matlab.ui.control.NumericEditField
        zEditField_2Label             matlab.ui.control.Label
        yEditField_2                  matlab.ui.control.NumericEditField
        yEditField_2Label             matlab.ui.control.Label
        xEditField_2                  matlab.ui.control.NumericEditField
        xEditField_2Label             matlab.ui.control.Label
        ZMinusButton                  matlab.ui.control.Button
        ZButton                       matlab.ui.control.Button
        UsebuttonstomovetheendeffectorpositionLabel  matlab.ui.control.Label
        YMinusButton                  matlab.ui.control.Button
        YButton                       matlab.ui.control.Button
        XMinusButton                  matlab.ui.control.Button
        XButton                       matlab.ui.control.Button
        MeasureandLogTab              matlab.ui.container.Tab
        LogSampleButton               matlab.ui.control.Button
        z_measSpinner                 matlab.ui.control.Spinner
        zSpinnerLabel                 matlab.ui.control.Label
        y_measSpinner                 matlab.ui.control.Spinner
        ySpinnerLabel                 matlab.ui.control.Label
        x_measSpinner                 matlab.ui.control.Spinner
        xSpinnerLabel                 matlab.ui.control.Label
        EntermeasuredXYZvaluesLabel   matlab.ui.control.Label
        OptimizationTab               matlab.ui.container.Tab
        FinalErrorEditField           matlab.ui.control.NumericEditField
        FinalErrorEditFieldLabel      matlab.ui.control.Label
        OptimizedParametersEditField  matlab.ui.control.EditField
        OptimizedParametersEditFieldLabel  matlab.ui.control.Label
        InitialErrorEditField         matlab.ui.control.NumericEditField
        InitialErrorEditFieldLabel    matlab.ui.control.Label
        OptimizeDHParametersButton    matlab.ui.control.Button
        RobotMargeLabel               matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        robot
        joint_params
        target_position
        stepXY = 0.5;
        stepZ = 0.5;
        logFileName = 'robot_log.csv';
    end
    
    methods (Access = private)
        
        % Startup function for initializing joint parameters.
        function startupFcn(app)
            app.joint_params = [0 0 0];

            % Create log file.
            fid = fopen(app.logFileName,'w');
            fprintf(fid, 'q1_deg,q2_deg,d3,x_pred,y_pred,z_pred,x_meas,y_meas,z_meas\n');
            fclose(fid);
        end

        % Helper function for plotting 2D model of robot.
        function robotModel2D(app)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first in the other tab.', 'No Robot');
                return
            end

            % Joint angles.
            q1 = app.joint_params(1);
            q2 = app.joint_params(2);

            % Link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;

            % Base point.
            x0 = 0;
            y0 = 0;

            % Joint 1 position.
            x1 = L1 * cos(q1);
            y1 = L1 * sin(q1);

            % Joint 2 position (end-effector).
            x2 = x1 + L2*cos(q1 + q2);
            y2 = y1 + L2*sin(q1 + q2);

            % Plot links and joints.
            cla(app.UIAxes);
            hold(app.UIAxes, 'on'); 
            plot(app.UIAxes, [x0 x1 x2], [y0 y1 y2], '-o')
            xlim(app.UIAxes, [-20, 20]);
            ylim(app.UIAxes, [-20, 20]);
            axis(app.UIAxes, 'equal');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end

        % Helper function for updating robot models when slider values change.
        function updateRobotPlot(app)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end

            % Read the sliders.
            q1 = deg2rad(app.Joint1Slider.Value);
            q2 = deg2rad(app.Joint2Slider.Value);
            d3 = app.Joint3Slider.Value;

            % Update the joint parameters.
            app.joint_params = [q1 q2 d3];

            % Plot the 3D model of robot at the new position/orientation.
            app.robot.plot(app.joint_params)

            % Plot the 2D model of robot.
            robotModel2D(app);
        end

        % Helper function for joint limits.
        function q = limitJoints(app, q)
            % Joint 1.
            q1min = app.robot.links(1).qlim(1);
            q1max = app.robot.links(1).qlim(2);

            if q(1) < q1min
                q(1) = q1min;
            end

            if q(1) > q1max
                q(1) = q1max;
            end

            % Joint 2.
            q2min = app.robot.links(2).qlim(1);
            q2max = app.robot.links(2).qlim(2);

            if q(2) < q2min
                q(2) = q2min;
            end

            if q(2) > q2max
                q(2) = q2max;
            end

            % Joint 3.
            d3min = app.robot.links(3).qlim(1);
            d3max = app.robot.links(3).qlim(2);

            if q(3) < d3min
                q(3) = d3min;
            end

            if q(3) > d3max
                q(3) = d3max;
            end
        end

        % Helper function for closed-form IK.
        function q = closedFormIK(app, x, y)
            % Link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;

            % Formulas below are from the in-class IK slides.
            cos2 = (x^2 + y^2 - L1^2 - L2^2) / (2 * L1 * L2);

            % Avoid errors.
            cos2 = max(min(cos2, 1), -1);

            % Closed-form IK.
            q2 = acos(cos2);
            q1 = atan2(y, x) - atan2(L2*sin(q2), L1 + L2*cos(q2));
            d3 = app.joint_params(3);
            q = [q1 q2 d3];

            % Apply joint limits.
            q = limitJoints(app, q);
        end

        % Helper function for numerical IK.
        function q = numericalIK(app, x, y)
            % Initial joint values and XYZ position.
            q0 = app.joint_params;
            T0 = app.robot.fkine(q0).T;
            z = T0(3,4);

            % Homogenous transform at the target position.
            T = transl(x, y, z);
            mask = [1 1 1 0 0 0];

            % Numerical IK.
            q = app.robot.ikine(T, 'mask', mask);

            % Apply joint limits.
            q = limitJoints(app, q);
        end

        % Helper function to update sliders, spinners, fields, and plots.
        function updateAll(app)
            q = app.joint_params;

            % Update sliders (deg for revolute).
            app.Joint1Slider.Value = rad2deg(q(1));
            app.Joint2Slider.Value = rad2deg(q(2));
            app.Joint3Slider.Value = q(3);

            % Update spinners.
            app.Joint1AngleSpinner.Value = rad2deg(q(1));
            app.Joint2AngleSpinner.Value = rad2deg(q(2));
            app.Joint3DistanceSpinner.Value = q(3);

            % FK to get XYZ.
            T = app.robot.fkine(q).T;

            x = T(1,4);
            y = T(2,4);
            z = T(3,4);

            % Show XYZ in GUI (if FK).
            app.xEditField.Value = x;
            app.yEditField.Value = y;
            app.zEditField.Value = z;

            app.xEditField_2.Value = x;
            app.yEditField_2.Value = y;
            app.zEditField_2.Value = z;

            % Show new joint parameters in GUI (if IK).
            app.REditField.Value = rad2deg(q(1));
            app.PEditField.Value = rad2deg(q(2));
            app.YEditField.Value = q(3);

            % Update 3D and 2D robot models.
            updateRobotPlot(app)
        end

        % Helper/objective function for calculating errors.
        function err = dhobjective(app, para)
            % para = [a1 a2]  (link lengths we want to optimize)

            % Read measurement file
            data = readmatrix('robot_log.csv');
            data = data(~any(isnan(data),2), :);

            % Columns:
            % 1: q1_deg, 2: q2_deg, 3: d3, 4: x_pred, 5: y_pred, 6: z_pred,
            % 7: x_meas, 8: y_meas, 9: z_meas
            q1_deg  = data(:,1);
            q2_deg  = data(:,2);
            d3_all  = data(:,3);
            x_meas  = data(:,7);
            y_meas  = data(:,8);
            z_meas  = data(:,9);

            % Build robot with current DH parameters para.
            a1 = para(1);
            a2 = para(2);

            d1 = 3.2;
            d2 = 0.5;

            L(1) = Link('revolute', 'd', d1, 'a', a1, 'alpha', 0, 'offset', deg2rad(8), 'qlim', deg2rad([-95 95]));
            L(2) = Link('revolute', 'd', d2, 'a', a2, 'alpha', pi, 'offset', deg2rad(-25), 'qlim', deg2rad([-130 15]));
            L(3) = Link('prismatic','theta', 0, 'a', 0, 'alpha', 0, 'offset', -3, 'qlim', [3 9]);

            marge = SerialLink(L, 'name', 'Robot Marge');

            T_base = transl(12.5, 20.5, 7.5) * trotz(60);
            marge.base = T_base;

            % Loop over all measurement rows and accumulate error
            n = size(data,1);
            total_error = 0;

            for i = 1:n
                % Joint values for this sample
                q1 = deg2rad(q1_deg(i));
                q2 = deg2rad(q2_deg(i));
                d3 = d3_all(i);

                q = [q1 q2 d3];

                % Forward kinematics with current DH
                T = app.robot.fkine(q).T;
                pos_sim = T(1:3,4);     % [x; y; z] simulated

                % Measured position
                pos_meas = [x_meas(i); y_meas(i); z_meas(i)];

                % Squared distance error
                e = pos_meas - pos_sim;
                total_error = total_error + sum(e.^2);
            end

            % Average error (mean squared error)
            err = total_error / n;
        end

    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateRobotButton
        function CreateRobotButtonPushed(app, event)
            % Define links with DH parameters.
            L(1) = Link('revolute', 'd', 3.2, 'a', 10.5, 'alpha', 0, 'offset', deg2rad(8), 'qlim', deg2rad([-95 95]));
            L(2) = Link('revolute', 'd', 0.5, 'a', 10.3, 'alpha', pi, 'offset', deg2rad(-25), 'qlim', deg2rad([-130 15]));
            L(3) = Link('prismatic', 'theta', 0, 'a', 0, 'alpha', 0, 'offset', -3, 'qlim', [3 9]);

            % Create robot.
            app.robot = SerialLink(L, 'name', 'Robot Marge');

            % Create base of robot.
            T_base = transl(12.5, 20.5, 7.5) * trotz(60);
            app.robot.base = T_base;

            % Reset joint parameters and sliders.
            app.joint_params = [0 0 3];
            app.Joint1Slider.Value = 0;
            app.Joint2Slider.Value = 0;
            app.Joint3Slider.Value = 3;

            % Plot robot.
            updateRobotPlot(app);
        end

        % Value changed function: Joint1Slider
        function Joint1SliderValueChanged(app, event)
            updateRobotPlot(app);
        end

        % Value changed function: Joint2Slider
        function Joint2SliderValueChanged(app, event)
            updateRobotPlot(app);
        end

        % Value changed function: Joint3Slider
        function Joint3SliderValueChanged(app, event)
            updateRobotPlot(app);
        end

        % Button pushed function: ForwardKinematicsButton
        function ForwardKinematicsButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first in the other tab.', 'No Robot');
                return
            end

            % some test values we can try later to see which XYZ positions we
            % get (and then move robot to these same values and find the XYZ
            % positions with the physical model):
            % q₁ = 0°, q₂ = 0°, d₃ = 3
            % 
            % q₁ = 90°, q₂ = 0°, d₃ = 3
            % 
            % q₁ = −90°, q₂ = 0°, d₃ = 3
            % 
            % q₁ = 0°, q₂ = -90°, d₃ = 3
            % 
            % q₁ = 45°, q₂ = −45°, d₃ = 3

            % Measure the errors:
            % Δx = measured x − predicted x
            % 
            % Δy = measured y − predicted y
            % 
            % Δz = measured z − predicted z
            % 
            % distance error = sqrt(Δx² + Δy² + Δz²)

            % Read joint values from spinners.
            q1_deg = app.Joint1AngleSpinner.Value;
            q2_deg = app.Joint2AngleSpinner.Value;
            d3 = app.Joint3DistanceSpinner.Value;

            % Convert to radians for forward kinematics.
            q1 = deg2rad(app.Joint1AngleSpinner.Value);
            q2 = deg2rad(app.Joint2AngleSpinner.Value);
            q = [q1 q2 d3];

            % Compute forward kinematics to get XYZ and extract 4x4.
            T = app.robot.fkine(q).T;

            % Extract XYZ from T.
            x = T(1,4);
            y = T(2,4);
            z = T(3,4);

            % Show XYZ values in GUI.
            app.xEditField.Value = x;
            app.yEditField.Value = y;
            app.zEditField.Value = z;

            % Ensure joint limits are satisfied.
            if q1_deg < -95
                q1_deg = -95;
                app.Joint1AngleSpinner.Value = q1_deg;
                app.Joint1Slider.Value = q1_deg;
            end

            if q1_deg > 95
                q1_deg = 95;
                app.Joint1AngleSpinner.Value = q1_deg;
                app.Joint1Slider.Value = q1_deg;
            end
            
            if q2_deg < -130
                q2_deg = -130;
                app.Joint2AngleSpinner.Value = q2_deg;
                app.Joint2Slider.Value = q2_deg;                
            end

            if q2_deg > 15
                q2_deg = 15;
                app.Joint2AngleSpinner.Value = q2_deg;
                app.Joint2Slider.Value = q2_deg;                
            end
            
            if d3 < 3
                d3 = 3;
                app.Joint3DistanceSpinner.Value = d3;
                app.Joint3Slider.Value = d3;                
            end

            if d3 > 9
                d3 = 9;                
                app.Joint3DistanceSpinner.Value = d3;
                app.Joint3Slider.Value = d3;                
            end

            % Make sliders match the spinners.
            app.Joint1Slider.Value = q1_deg;
            app.Joint2Slider.Value = q2_deg;
            app.Joint3Slider.Value = d3;

            % Update the 2D and 3D models.
            updateRobotPlot(app);
        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
                
            % Retrieve joint angles.
            q1 = app.joint_params(1);
            q2 = app.joint_params(2);
        
            % Retrieve link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;
        
            % Use formulas from IK slides to get x and y.
            x = L1*cos(q1) + L2*cos(q1 + q2);
            y = L1*sin(q1) + L2*sin(q1 + q2);
        
            % Increment x by step value.
            x_new = x + app.stepXY;
        
            % Perform closed-form IK to get angles from XYZ.
            q_new = closedFormIK(app, x_new, y);
            q_new(3) = app.joint_params(3);
            q_new = limitJoints(app, q_new);
        
            % Save and update everything in GUI.
            app.joint_params = q_new;
            updateAll(app);
        end

        % Button pushed function: XMinusButton
        function XMinusButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
                
            % Retrieve joint angles.
            q1 = app.joint_params(1);
            q2 = app.joint_params(2);
        
            % Retrieve link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;
        
            % Use formulas from IK slides to get x and y.
            x = L1*cos(q1) + L2*cos(q1 + q2);
            y = L1*sin(q1) + L2*sin(q1 + q2);
        
            % Decrement x by step value.
            x_new = x - app.stepXY;
        
            % Perform closed-form IK to get angles from XYZ.
            q_new = closedFormIK(app, x_new, y);
            q_new(3) = app.joint_params(3);
            q_new = limitJoints(app, q_new);
        
            % Save and update everything in GUI.
            app.joint_params = q_new;
            updateAll(app);
        end

        % Button pushed function: YButton
        function YButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
                
            % Retrieve joint angles.
            q1 = app.joint_params(1);
            q2 = app.joint_params(2);
        
            % Retrieve link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;
        
            % Use formulas from IK slides to get x and y.
            x = L1*cos(q1) + L2*cos(q1 + q2);
            y = L1*sin(q1) + L2*sin(q1 + q2);
        
            % Increment y by step value.
            y_new = y + app.stepXY;
        
            % Perform closed-form IK to get angles from XYZ.
            q_new = closedFormIK(app, x, y_new);
            q_new(3) = app.joint_params(3);
            q_new = limitJoints(app, q_new);
        
            % Save and update everything in GUI.
            app.joint_params = q_new;
            updateAll(app);
        end

        % Button pushed function: YMinusButton
        function YMinusButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
                
            % Retrieve joint angles.
            q1 = app.joint_params(1);
            q2 = app.joint_params(2);
        
            % Retrieve link lengths.
            L1 = app.robot.links(1).a;
            L2 = app.robot.links(2).a;
        
            % Use formulas from IK slides to get x and y.
            x = L1*cos(q1) + L2*cos(q1 + q2);
            y = L1*sin(q1) + L2*sin(q1 + q2);
        
            % Decrement y by step value.
            y_new = y - app.stepXY;
        
            % Perform closed-form IK to get angles from XYZ.
            q_new = closedFormIK(app, x, y_new);
            q_new(3) = app.joint_params(3);
            q_new = limitJoints(app, q_new);
        
            % Save and update everything in GUI.
            app.joint_params = q_new;
            updateAll(app);
        end

        % Button pushed function: ZButton
        function ZButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
            
            q = app.joint_params;
        
            % Increase prismatic joint.
            q(3) = q(3) + app.stepZ;
        
            % Apply limits.
            q = limitJoints(app, q);
       
            % Save and update everything in GUI.
            app.joint_params = q;
            updateAll(app);
        end

        % Button pushed function: ZMinusButton
        function ZMinusButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
        
            q = app.joint_params;
        
            % Decrease prismatic joint.
            q(3) = q(3) - app.stepZ;
        
            % Apply limits.
            q = limitJoints(app, q);
        
            % Save and update everything in GUI.
            app.joint_params = q;
            updateAll(app);
        end

        % Button pushed function: ClosedFormIKButton
        function ClosedFormIKButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end
            
            % Get x and y from the fields in IK tab in GUI.
            x = app.xEditField_2.Value;
            y = app.yEditField_2.Value;

            % Perform closed-form IK.
            q_new = closedFormIK(app, x, y);
            q_new = limitJoints(app, q_new);

            % Save and update everything in GUI.
            app.joint_params = q_new;
            updateAll(app);
        end

        % Button pushed function: NumericalIKButton
        function NumericalIKButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end

            % Get x and y from the fields in the IK tab in GUI.
            x = app.xEditField_2.Value;
            y = app.yEditField_2.Value;

            % Perform numerical IK.
            q_new = numericalIK(app, x, y);
            q_new = limitJoints(app, q_new);

            % Save and update everything in GUI.
            app.joint_params = q_new;
            updateAll(app);            
        end

        % Button pushed function: LogSampleButton
        function LogSampleButtonPushed(app, event)
            if isempty(app.robot)
                uialert(app.UIFigure, 'Create the robot first.', 'No Robot');
                return
            end

            % Joint angles from the GUI (deg for revolute, cm for prismatic).
            q1 = app.Joint1AngleSpinner.Value;
            q2 = app.Joint2AngleSpinner.Value;
            d3 = app.Joint3DistanceSpinner.Value;

            % Predicted EE position from your model (in FK tab).
            x_pred = app.xEditField.Value;
            y_pred = app.yEditField.Value;
            z_pred = app.zEditField.Value;

            % Measured EE position on the clipboard.
            x_meas = app.x_measSpinner.Value;
            y_meas = app.y_measSpinner.Value;
            z_meas = app.z_measSpinner.Value;

            % Append one line to the CSV file
            fid = fopen(app.logFileName, 'a');
            if fid == -1
                uialert(app.UIFigure, 'Could not open log file for writing.', 'File Error');
                return
            end

            fprintf(fid, '%f,%f,%f,%f,%f,%f,%f,%f,%f\n', ...
                q1, q2, d3, x_pred, y_pred, z_pred, x_meas, y_meas, z_meas);

            fclose(fid);

            % Confirmation in command window.
            disp('Logged one sample to robot_log.csv');
        end

        % Button pushed function: OptimizeDHParametersButton
        function OptimizeDHParametersButtonPushed(app, event)
            % Make sure files exist.
            if ~isfile(app.logFileName)
                uialert(app.UIFigure, 'Log file not found. Log some data first.', ...
                    'No Data File');
                return
            end

            % Initial parameters (from current robot).
            a1_0 = app.robot.links(1).a;
            a2_0 = app.robot.links(2).a;
            para0 = [a1_0 a2_0];

            % Initial error.
            err0 = dhobjective(app, para0);
            app.InitialErrorEditField.Value = err0;

            % Run fminsearch.
            options = optimset('Display','off');  % keep output clean
            [para_opt, err_opt] = fminsearch(@(p) dhobjective(app, p), para0);

            % Show optimized parameters and final error.
            app.OptimizedParametersEditField.Value = sprintf('a1 = %.2f,  a2 = %.2f', para_opt(1), para_opt(2));
            app.FinalErrorEditField.Value = err_opt;

            % Get data.
            data = readmatrix(app.logFileName);
            data = data(~any(isnan(data),2), :);

            q1_deg = data(:,1);
            q2_deg = data(:,2);
            d3_all = data(:,3);
            x_meas = data(:,7);
            y_meas = data(:,8);

            % Build robot with optimized DH.
            d1 = 3.2;  d2 = 0.5;
            L(1) = Link('revolute', 'd', d1, 'a', para_opt(1), 'alpha', 0, 'offset', deg2rad(8), 'qlim', deg2rad([-95 95]));
            L(2) = Link('revolute', 'd', d2, 'a', para_opt(2), 'alpha', pi, 'offset', deg2rad(-25), 'qlim', deg2rad([-130 15]));
            L(3) = Link('prismatic','theta', 0, 'a', 0, 'alpha', 0, 'offset', -3, 'qlim', [3 9]);

            robot_opt = SerialLink(L, 'name', 'Robot Marge Opt');

            % Create base of optimized robot.
            T_base = transl(12.5, 20.5, 7.5) * trotz(60);
            robot_opt.base = T_base;

            n = size(data,1);
            x_sim = zeros(n,1);
            y_sim = zeros(n,1);

            for i = 1:n
                q1 = deg2rad(q1_deg(i));
                q2 = deg2rad(q2_deg(i));
                d3 = d3_all(i);
                T = robot_opt.fkine([q1 q2 d3]).T;
                x_sim(i) = T(1,4);
                y_sim(i) = T(2,4);
            end

            cla(app.UIAxes);
            hold(app.UIAxes, 'on');
            plot(app.UIAxes, x_meas, y_meas, 'ko', 'DisplayName','Measured');
            plot(app.UIAxes, x_sim,  y_sim,  'rx', 'DisplayName','Sim (optimized)');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            title(app.UIAxes, 'Measured vs Optimized FK');
            legend(app.UIAxes, 'Location','best');
            grid(app.UIAxes, 'on');
            hold(app.UIAxes, 'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Robot Marge')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RobotMargeLabel = uilabel(app.UIFigure);
            app.RobotMargeLabel.Text = 'Robot Marge';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.CreateRobotTab = uitab(app.TabGroup);
            app.CreateRobotTab.Title = 'Create Robot';
            app.CreateRobotButton = uibutton(app.CreateRobotTab, 'push');
            app.CreateRobotButton.ButtonPushedFcn = createCallbackFcn(app, @CreateRobotButtonPushed, true);
            app.CreateRobotButton.Text = 'Create Robot';
            app.Joint1SliderLabel = uilabel(app.CreateRobotTab);
            app.Joint1SliderLabel.Text = 'Joint 1';
            app.Joint1Slider = uislider(app.CreateRobotTab);
            app.Joint1Slider.Limits = [-95 95];
            app.Joint1Slider.MajorTicks = [-95 0 95];
            app.Joint1Slider.MajorTickLabels = {'-95', '0', '95'};
            app.Joint1Slider.ValueChangedFcn = createCallbackFcn(app, @Joint1SliderValueChanged, true);
            app.Joint1Slider.MinorTicks = [];
            app.Joint2SliderLabel = uilabel(app.CreateRobotTab);
            app.Joint2SliderLabel.Text = 'Joint 2';
            app.Joint2Slider = uislider(app.CreateRobotTab);
            app.Joint2Slider.Limits = [-130 15];
            app.Joint2Slider.MajorTicks = [-130 15];
            app.Joint2Slider.MajorTickLabels = {'-130', '15'};
            app.Joint2Slider.ValueChangedFcn = createCallbackFcn(app, @Joint2SliderValueChanged, true);
            app.Joint2Slider.MinorTicks = [];
            app.Joint3Slider = uislider(app.CreateRobotTab);
            app.Joint3Slider.Limits = [0 15];
            app.Joint3Slider.MajorTicks = [0 7.5 15];
            app.Joint3Slider.MajorTickLabels = {'0', '7.5', '15'};
            app.Joint3Slider.ValueChangedFcn = createCallbackFcn(app, @Joint3SliderValueChanged, true);
            app.Joint3Slider.MinorTicks = [];
            app.CreatetherobotandmoveitusingtheslidersLabel = uilabel(app.CreateRobotTab);
            app.CreatetherobotandmoveitusingtheslidersLabel.Text = {'Create the robot and move it '; 'using the sliders:'};
            app.Joint3Slider_2Label = uilabel(app.CreateRobotTab);
            app.Joint3Slider_2Label.Text = 'Joint 3';
            app.KinematicsTab = uitab(app.TabGroup);
            app.KinematicsTab.Title = 'Kinematics';
            app.TabGroup2 = uitabgroup(app.KinematicsTab);
            app.ForwardKinematicsTab = uitab(app.TabGroup2);
            app.ForwardKinematicsTab.Title = 'Forward Kinematics';
            app.ForwardKinematicsButton = uibutton(app.ForwardKinematicsTab, 'push');
            app.ForwardKinematicsButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardKinematicsButtonPushed, true);
            app.ForwardKinematicsButton.Text = 'Forward Kinematics';
            app.EnterthejointparametersLabel = uilabel(app.ForwardKinematicsTab);
            app.EnterthejointparametersLabel.Text = 'Enter the joint parameters:';
            app.xEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.yEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.zEditFieldLabel = uilabel(app.ForwardKinematicsTab);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.ForwardKinematicsTab, 'numeric');
            app.Joint1AngleSpinnerLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint1AngleSpinnerLabel.Text = 'Joint 1 (Angle)';
            app.Joint1AngleSpinner = uispinner(app.ForwardKinematicsTab);
            app.Joint2AngleSpinnerLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint2AngleSpinnerLabel.Text = 'Joint 2 (Angle)';
            app.Joint2AngleSpinner = uispinner(app.ForwardKinematicsTab);
            app.Joint3DistanceSpinnerLabel = uilabel(app.ForwardKinematicsTab);
            app.Joint3DistanceSpinnerLabel.Text = 'Joint 3 (Distance)';
            app.Joint3DistanceSpinner = uispinner(app.ForwardKinematicsTab);
            app.InverseKinematicsTab = uitab(app.TabGroup2);
            app.InverseKinematicsTab.Title = 'Inverse Kinematics';
            app.XButton = uibutton(app.InverseKinematicsTab, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.XMinusButton = uibutton(app.InverseKinematicsTab, 'push');
            app.XMinusButton.ButtonPushedFcn = createCallbackFcn(app, @XMinusButtonPushed, true);
            app.XMinusButton.Text = '-X';
            app.YButton = uibutton(app.InverseKinematicsTab, 'push');
            app.YButton.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.YButton.Text = 'Y';
            app.YMinusButton = uibutton(app.InverseKinematicsTab, 'push');
            app.YMinusButton.ButtonPushedFcn = createCallbackFcn(app, @YMinusButtonPushed, true);
            app.YMinusButton.Text = '-Y';
            app.UsebuttonstomovetheendeffectorpositionLabel = uilabel(app.InverseKinematicsTab);
            app.UsebuttonstomovetheendeffectorpositionLabel.Text = {'Use buttons to move the '; 'end-effector position:'};
            app.ZButton = uibutton(app.InverseKinematicsTab, 'push');
            app.ZButton.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.ZButton.Text = 'Z';
            app.ZMinusButton = uibutton(app.InverseKinematicsTab, 'push');
            app.ZMinusButton.ButtonPushedFcn = createCallbackFcn(app, @ZMinusButtonPushed, true);
            app.ZMinusButton.Text = '-Z';
            app.xEditField_2Label = uilabel(app.InverseKinematicsTab);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.yEditField_2Label = uilabel(app.InverseKinematicsTab);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.zEditField_2Label = uilabel(app.InverseKinematicsTab);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.REditField_2Label = uilabel(app.InverseKinematicsTab);
            app.REditField_2Label.Text = 'R';
            app.REditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.PEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.YEditFieldLabel = uilabel(app.InverseKinematicsTab);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.InverseKinematicsTab, 'numeric');
            app.NumericalIKButton = uibutton(app.InverseKinematicsTab, 'push');
            app.NumericalIKButton.ButtonPushedFcn = createCallbackFcn(app, @NumericalIKButtonPushed, true);
            app.NumericalIKButton.Text = 'Numerical IK';
            app.ClosedFormIKButton = uibutton(app.InverseKinematicsTab, 'push');
            app.ClosedFormIKButton.ButtonPushedFcn = createCallbackFcn(app, @ClosedFormIKButtonPushed, true);
            app.ClosedFormIKButton.Text = 'Closed-Form IK';
            app.MeasureandLogTab = uitab(app.TabGroup);
            app.MeasureandLogTab.Title = 'Measure and Log';
            app.EntermeasuredXYZvaluesLabel = uilabel(app.MeasureandLogTab);
            app.EntermeasuredXYZvaluesLabel.Text = 'Enter measured XYZ values:';
            app.xSpinnerLabel = uilabel(app.MeasureandLogTab);
            app.xSpinnerLabel.Text = 'x';
            app.x_measSpinner = uispinner(app.MeasureandLogTab);
            app.ySpinnerLabel = uilabel(app.MeasureandLogTab);
            app.ySpinnerLabel.Text = 'y';
            app.y_measSpinner = uispinner(app.MeasureandLogTab);
            app.zSpinnerLabel = uilabel(app.MeasureandLogTab);
            app.zSpinnerLabel.Text = 'z';
            app.z_measSpinner = uispinner(app.MeasureandLogTab);
            app.LogSampleButton = uibutton(app.MeasureandLogTab, 'push');
            app.LogSampleButton.ButtonPushedFcn = createCallbackFcn(app, @LogSampleButtonPushed, true);
            app.LogSampleButton.Text = 'Log Sample';
            app.OptimizationTab = uitab(app.TabGroup);
            app.OptimizationTab.Title = 'Optimization';
            app.OptimizeDHParametersButton = uibutton(app.OptimizationTab, 'push');
            app.OptimizeDHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @OptimizeDHParametersButtonPushed, true);
            app.OptimizeDHParametersButton.Text = 'Optimize DH Parameters';
            app.InitialErrorEditFieldLabel = uilabel(app.OptimizationTab);
            app.InitialErrorEditFieldLabel.Text = 'Initial Error';
            app.InitialErrorEditField = uieditfield(app.OptimizationTab, 'numeric');
            app.OptimizedParametersEditFieldLabel = uilabel(app.OptimizationTab);
            app.OptimizedParametersEditFieldLabel.Text = 'Optimized Parameters';
            app.OptimizedParametersEditField = uieditfield(app.OptimizationTab, 'text');
            app.FinalErrorEditFieldLabel = uilabel(app.OptimizationTab);
            app.FinalErrorEditFieldLabel.Text = 'Final Error';
            app.FinalErrorEditField = uieditfield(app.OptimizationTab, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot_marge

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end